classdef Animate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PauseButton               matlab.ui.control.Button
        AnimateButton             matlab.ui.control.Button
        FileSelectedEditField     matlab.ui.control.EditField
        FileSelectedLabel         matlab.ui.control.Label
        LoadinDataLabel           matlab.ui.control.Label
        LoadDataSelectFileButton  matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        %%% Raw Data
            NPY 
            cur_j = 1;
      
        %%% Animation Status
            stop = false 
            
    end
    
    methods (Access = private)
     

        function plot_animation(app)
            %%% Animate the Time Series
            % Plot the first element and initialize counter
                    p = plot(app.UIAxes,squeeze(app.NPY(app.cur_j,:)));

            % Plot Labels
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes, 'time (s)');
                ylabel(app.UIAxes, '\eta');
                ylim(app.UIAxes,[1.1*min(min(app.NPY)) 1.1*max(max(app.NPY))])

            % Loop through all time steps
                for j = app.cur_j:size(app.NPY,2)
                    % Only progress if Stop isn't checked
                    if app.stop == false
                        % Update plot elements
                        set(p, 'YData', squeeze(app.NPY(j,:)))
                        title(app.UIAxes, ['Time Step = ', num2str(j)]);
    
                        
                        app.cur_j = j;
                        % Draw now to update
                        drawnow
                        pause(0.05)

                    % If stop is Checked, then break out of loop
                    else
                        app.cur_j = j;
                        break
                    end
                end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataSelectFileButton
        function LoadDataSelectFileButtonPushed(app, event)
            % Dummy Figure for UI Ease
                f = figure('Position', [-100 -100 0 0]);
            % Select Path
                [file,path] = uigetfile();
                
            % Delete Dummy Figure
                delete(f);
            
            % Save paths to data
                app.NPY = readNPY(fullfile(path,file));
            
            % Display Folder Selected    
                app.FileSelectedEditField.Value = file;

        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            plot_animation(app)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.stop == true
             app.PauseButton.Text = "Pause";
            else
                app.PauseButton.Text = "Resume";
            end

             app.stop = ~app.stop;
             plot_animation(app);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {346, 346};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataSelectFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataSelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSelectFileButtonPushed, true);
            app.LoadDataSelectFileButton.Text = 'Load Data: Select File';
            app.LoadinDataLabel = uilabel(app.LeftPanel);
            app.LoadinDataLabel.Text = 'Load in Data';
            app.FileSelectedLabel = uilabel(app.LeftPanel);
            app.FileSelectedLabel.Text = {'File '; 'Selected'};
            app.FileSelectedEditField = uieditfield(app.LeftPanel, 'text');
            app.AnimateButton = uibutton(app.LeftPanel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.PauseButton = uibutton(app.LeftPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'FUNWAVE Model Results')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, '\eta')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Animate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end