classdef FW_1D_Time_Series_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Param_Panel                     matlab.ui.container.Panel
        T_INTVMEAN                      matlab.ui.control.NumericEditField
        T_INTVMEANEditFieldLabel        matlab.ui.control.Label
        WAVEMAKER                       matlab.ui.control.EditField
        WAVEMAKEREditFieldLabel         matlab.ui.control.Label
        DIFFUSIONSPONGE                 matlab.ui.control.EditField
        DIFFUSIONSPONGEEditField_2Label  matlab.ui.control.Label
        Cbrk2                           matlab.ui.control.NumericEditField
        Cbrk2EditFieldLabel             matlab.ui.control.Label
        Cbrk1                           matlab.ui.control.NumericEditField
        Cbrk1EditFieldLabel             matlab.ui.control.Label
        Cd                              matlab.ui.control.NumericEditField
        CdEditFieldLabel                matlab.ui.control.Label
        LineLabel                       matlab.ui.control.Label
        SpongeDimensionsLabel           matlab.ui.control.Label
        Sponge_N                        matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        Sponge_S                        matlab.ui.control.NumericEditField
        SEditFieldLabel                 matlab.ui.control.Label
        Sponge_E                        matlab.ui.control.NumericEditField
        EEditFieldLabel                 matlab.ui.control.Label
        Sponge_W                        matlab.ui.control.NumericEditField
        WEditFieldLabel                 matlab.ui.control.Label
        Sponge_Csp                      matlab.ui.control.NumericEditField
        CspEditFieldLabel               matlab.ui.control.Label
        CDSponge                        matlab.ui.control.NumericEditField
        CDSpongeEditFieldLabel          matlab.ui.control.Label
        DIRECTSPONGE                    matlab.ui.control.EditField
        DIRECTSPONGEEditFieldLabel      matlab.ui.control.Label
        FRICTIONSPONGE                  matlab.ui.control.EditField
        FRICTIONSPONGEEditFieldLabel    matlab.ui.control.Label
        VISCOSITYBREAKING               matlab.ui.control.EditField
        VISCOSITYBREAKINGEditFieldLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        DEP_WK                          matlab.ui.control.NumericEditField
        DEP_WKEditFieldLabel            matlab.ui.control.Label
        Theta_WK                        matlab.ui.control.NumericEditField
        Theta_WKEditFieldLabel          matlab.ui.control.Label
        Xc_WK_Box                       matlab.ui.control.NumericEditField
        Xc_WKEditFieldLabel             matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MinDepth                        matlab.ui.control.NumericEditField
        MinDepthEditFieldLabel          matlab.ui.control.Label
        FroudeCap                       matlab.ui.control.NumericEditField
        FroudeCapEditFieldLabel         matlab.ui.control.Label
        SCREEN_INTV                     matlab.ui.control.NumericEditField
        SCREEN_INTVEditFieldLabel       matlab.ui.control.Label
        PLOT_INTV                       matlab.ui.control.NumericEditField
        PLOT_INTVEditFieldLabel         matlab.ui.control.Label
        CFL                             matlab.ui.control.NumericEditField
        CFLEditFieldLabel               matlab.ui.control.Label
        TOTAL_TIME                      matlab.ui.control.NumericEditField
        TOTAL_TIMEEditFieldLabel        matlab.ui.control.Label
        PY                              matlab.ui.control.NumericEditField
        PYEditFieldLabel                matlab.ui.control.Label
        PX                              matlab.ui.control.NumericEditField
        PXEditFieldLabel                matlab.ui.control.Label
        DY                              matlab.ui.control.NumericEditField
        DYEditFieldLabel                matlab.ui.control.Label
        DX                              matlab.ui.control.NumericEditField
        DXEditFieldLabel                matlab.ui.control.Label
        Nglob                           matlab.ui.control.NumericEditField
        NglobEditFieldLabel             matlab.ui.control.Label
        Mglob                           matlab.ui.control.NumericEditField
        MglobEditFieldLabel             matlab.ui.control.Label
        FunwaveConstantParametersLabel  matlab.ui.control.Label
        DataFolder                      matlab.ui.control.EditField
        LoadinDataLabel                 matlab.ui.control.Label
        PointtoPlotEditField            matlab.ui.control.NumericEditField
        PointtoPlotEditFieldLabel       matlab.ui.control.Label
        DataFolderButton                matlab.ui.control.Button
        FunwaveVariableParametersLabel  matlab.ui.control.Label
        TPERIODEditField                matlab.ui.control.NumericEditField
        TPERIODEditFieldLabel           matlab.ui.control.Label
        AMP_WKEditField                 matlab.ui.control.NumericEditField
        AMP_WKEditFieldLabel            matlab.ui.control.Label
        SLPEditField                    matlab.ui.control.NumericEditField
        SLPEditFieldLabel               matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        Plot_Panel                      matlab.ui.container.Panel
        Xslp_Box                        matlab.ui.control.NumericEditField
        IterationLabel_5                matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        INPUTSLabel                     matlab.ui.control.Label
        Input_TPERIOD                   matlab.ui.control.NumericEditField
        IterationLabel_4                matlab.ui.control.Label
        Input_AMP_WK                    matlab.ui.control.NumericEditField
        IterationLabel_3                matlab.ui.control.Label
        Input_SLP                       matlab.ui.control.NumericEditField
        IterationLabel_2                matlab.ui.control.Label
        Iteration_No                    matlab.ui.control.NumericEditField
        IterationLabel                  matlab.ui.control.Label
        TrialBeingPlottedLabel          matlab.ui.control.Label
        Asy                             matlab.ui.control.NumericEditField
        AsymmetryEditFieldLabel         matlab.ui.control.Label
        Skew                            matlab.ui.control.NumericEditField
        SkewEditFieldLabel              matlab.ui.control.Label
        SkewandAsymmetryLabel           matlab.ui.control.Label
        SkasyGraph                      matlab.ui.control.UIAxes
        BathyGraph                      matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% PROPERTIES %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
    properties (Access = public)
        %%% Raw Data
            NPY 
            sumvars
            sumconst
        %%% Skew and Asymmetry Data
            skew_tab
            asy_tab
            start_t_tab

        %%% Default Variables
            % FUNWAVE Variable inputs
                SLP=0.05    
                AMPWK=1
                TPERIOD=9
            % Point in the model domain to plot
                point=900
                
        %%% Index of closest matching trial
            ind
        
    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%% PRIVATE METHODS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
        
        %% find_closest_trial
            % Find the trial in the dataset that's closest to the input
            % parameters
        function find_closest_trial(app)
            % Find which trial best matches the input values
            distances = sqrt((app.sumvars.AMP_WK - app.AMPWK).^2 + ...
                 (app.sumvars.Tperiod - app.TPERIOD).^2 + ...
                 (app.sumvars.SLP - app.SLP).^2);
            [~, app.ind] = min(distances);
        end

        %% plot_time_series
            % Plot the time series
        function plot_time_series(app)
            %%% Get variables to plot
            % Pull out time series
                eta = squeeze(app.NPY(app.ind,:,app.point));
            % Pull out start time for skew/asymmetry
                start_time = app.start_t_tab{app.ind,app.point};

            %%% Plot the time series
            % Plot Setup
                cla(app.UIAxes);
                hold(app.UIAxes,"on")
            % Plot Calls
                plot(app.UIAxes,eta)
                scatter(app.UIAxes,start_time,eta(start_time), 'filled', 'o','MarkerEdgeColor','r','MarkerFaceColor','r')
            % Plot Labels and Formatting 
                legend(app.UIAxes,'','Start Time');
                title(app.UIAxes, ['Time Series at Point ', num2str(app.point)]);
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes, 'time (s)');
                ylabel(app.UIAxes, '\eta');
            
            %%% Sidebar Information
            % Funwave Constants and Info
                app.Iteration_No.Value = app.ind;
                app.Input_SLP.Value = app.sumvars{app.ind,'SLP'};
                app.Input_AMP_WK.Value = app.sumvars{app.ind,'AMP_WK'};
                app.Input_TPERIOD.Value = app.sumvars{app.ind,'Tperiod'};
                app.Xslp_Box.Value = app.sumvars{app.ind,'Xslp'};
            % Skew/Asymmetry
                app.Skew.Value = app.skew_tab{app.ind,app.point};
                app.Asy.Value = app.asy_tab{app.ind,app.point};
        end
        
        %% plot_bathy
            % Plot the bathymetry
        function plot_bathy(app)
            %%% Pull out relevant variables
                Xslp = app.sumvars{app.ind,'Xslp'};
                dep = -app.sumconst.DEPTH_FLAT;
                Xc_WK = app.sumconst.Xc_WK;
                Sp_W = app.sumconst.Sponge_west_width;

            %%% Plot the bathymetry
            % Plot Setup
                cla(app.BathyGraph) 
                hold(app.BathyGraph,"on")
            % Plot Calls
                % Bed
                    plot(app.BathyGraph,[0,Xslp],[dep,dep],'Color','k','LineWidth',2)
                    plot(app.BathyGraph,[Xslp,1024],[dep,0],'Color','k','LineWidth',2)
                % Location of Xslp
                    scatter(app.BathyGraph,Xslp,dep, 'filled', 'o','MarkerEdgeColor','m','MarkerFaceColor','m');
                % Location of Sponge Layer
                    xline(app.BathyGraph,Sp_W,'Color','r','LineWidth',2, 'LineStyle','--');
                % Location of Wavemaker
                    xline(app.BathyGraph,Xc_WK,'Color','b','LineWidth',2, 'LineStyle','--');
                % Location of Point being plotted
                xline(app.BathyGraph,app.point,'Color','g','LineWidth',2, 'LineStyle','--');
            % Plot Labels and Formatting 
                grid(app.BathyGraph,'on')
                xlim(app.BathyGraph,[0,1024]);
                legend(app.BathyGraph,'','','Xslp','Sponge Layer', 'WaveMaker', 'Point of Time Series','Location','NorthWest');
        end
        
        %% plot_skasy
            % Plot the skew and asymmetry
        function plot_skasy(app)
            %%% Get Variables to Plot
                 skew_p = app.skew_tab{app.ind,:};
                 asy_p = app.asy_tab{app.ind,:};
                 Xc_WK = app.sumconst.Xc_WK;
                 Sp_W = app.sumconst.Sponge_west_width;
            %%% Plot the Skew and Asymmetry
            % Plot Setup
                cla(app.SkasyGraph)
                hold(app.SkasyGraph,"on");
            % Plot Calls
                % Skew and Asymmetry
                    plot(app.SkasyGraph,1:1024,skew_p,'Color','b');
                    plot(app.SkasyGraph,1:1024,asy_p,'Color','r');
                % Location of Sponge Layer
                    xline(app.SkasyGraph,Sp_W,'Color','r','LineWidth',2, 'LineStyle','--');
                % Location of Wavemaker
                    xline(app.SkasyGraph,Xc_WK,'Color','b','LineWidth',2, 'LineStyle','--');
                % Location of Point being plotted
                    xline(app.SkasyGraph,app.point,'Color','g','LineWidth',2, 'LineStyle','--');
            % Plot Labels and Formatting 
                xlim(app.SkasyGraph,[0,1024]);
                legend(app.SkasyGraph,'Skew','Asymmetry','Sponge Layer', 'WaveMaker', 'Point of Time Series','Location','Southeast');
                grid(app.SkasyGraph,'on')
        end

        %% populate_constants
            % Populate the values of the constants on the left
        function populate_constants(app)
            sumconsth = app.sumconst;
            app.Mglob.Value = sumconsth.Mglob;
            app.Nglob.Value = sumconsth.Nglob;

            app.DX.Value = sumconsth.DX;
            app.DY.Value = sumconsth.DY;

            app.PX.Value = sumconsth.PX;
            app.PY.Value = sumconsth.PY;

            app.TOTAL_TIME.Value = sumconsth.TOTAL_TIME;
            app.PLOT_INTV.Value = sumconsth.PLOT_INTV;
            app.SCREEN_INTV.Value = sumconsth.SCREEN_INTV;

            app.CFL.Value = sumconsth.CFL;
            app.FroudeCap.Value= sumconsth.FroudeCap;
            app.MinDepth.Value= sumconsth.MinDepth;

            app.WAVEMAKER.Value = sumconsth.WAVEMAKER{1};
            app.DEP_WK.Value = sumconsth.DEP_WK;
            app.Xc_WK_Box.Value = sumconsth.Xc_WK;
            app.Theta_WK.Value = sumconsth.Theta_WK;
            app.DIFFUSIONSPONGE.Value = sumconsth.DIFFUSION_SPONGE{1};
            app.FRICTIONSPONGE.Value = sumconsth.FRICTION_SPONGE{1};
            app.DIRECTSPONGE.Value = sumconsth.DIRECT_SPONGE{1};

            app.Sponge_Csp.Value = sumconsth.Csp;
            app.CDSponge.Value = sumconsth.CDsponge;

            app.Sponge_S.Value = sumconsth.Sponge_south_width;
            app.Sponge_N.Value = sumconsth.Sponge_north_width;
            app.Sponge_W.Value = sumconsth.Sponge_west_width;
            app.Sponge_E.Value = sumconsth.Sponge_east_width;

            app.VISCOSITYBREAKING.Value = sumconsth.VISCOSITY_BREAKING{1};
            app.T_INTVMEAN.Value = sumconsth.T_INTV_mean;

        end


    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              screenSize = get(groot,'ScreenSize');
              screenWidth = screenSize(3);
              screenHeight = screenSize(4);
              left = screenWidth*0.1;
              bottom = screenHeight*0.05;
              width = screenWidth*0.85;
              height = screenHeight*0.875;
              drawnow;
              app.UIFigure.Position = [left bottom width height]; 
        end

        % Value changed function: SLPEditField
        function SLPEditFieldValueChanged(app, event)
            app.SLP = app.SLPEditField.Value;
        end

        % Value changed function: AMP_WKEditField
        function AMP_WKEditFieldValueChanged(app, event)
            app.AMPWK = app.AMP_WKEditField.Value;
        end

        % Value changed function: TPERIODEditField
        function TPERIODEditFieldValueChanged(app, event)
            app.TPERIOD = app.TPERIODEditField.Value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            find_closest_trial(app);
            plot_time_series(app);
            plot_bathy(app);
            plot_skasy(app);
            
        end

        % Button pushed function: DataFolderButton
        function DataFolderButtonPushed(app, event)
            
            %%% Select Path
            % Dummy Figure for UI Ease
                f = figure('Position', [-100 -100 0 0]);
            % Select Path
                rawpath = uigetdir();
                skasypath = fullfile(rawpath,'Skasy');
            % Delete Dummy Figure
                delete(f);

            % Save Raw Data
                app.NPY = readNPY(fullfile(rawpath,'data_1D.npy'));
                app.sumvars = readtable(fullfile(rawpath,'sumvars.txt'));
                app.sumconst = readtable(fullfile(rawpath,'sumconst.txt'));
            % Save Skew and Asymmetry
                app.skew_tab = readtable(fullfile(skasypath,'skew_tab.txt'),'ReadVariableNames',true);
                app.asy_tab = readtable(fullfile(skasypath,'asy_tab.txt'),'ReadVariableNames',true);
                app.start_t_tab = readtable(fullfile(skasypath,'start_t_tab.txt'),'ReadVariableNames',true);
            
            % Display Folder Selected
                app.DataFolder.Value = rawpath;
            % Populate sidebar with information about constants
                populate_constants(app);


        end

        % Value changed function: PointtoPlotEditField
        function PointtoPlotEditFieldValueChanged(app, event)
            app.point = app.PointtoPlotEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {861, 861};
                app.GridLayout.ColumnWidth = {'1x'};
                app.Plot_Panel.Layout.Row = 2;
                app.Plot_Panel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {296, '1x'};
                app.Plot_Panel.Layout.Row = 1;
                app.Plot_Panel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Param_Panel = uipanel(app.GridLayout);
            app.PlotButton = uibutton(app.Param_Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SLPEditFieldLabel = uilabel(app.Param_Panel);
            app.SLPEditFieldLabel.Text = 'SLP';
            app.SLPEditField = uieditfield(app.Param_Panel, 'numeric');
            app.SLPEditField.ValueChangedFcn = createCallbackFcn(app, @SLPEditFieldValueChanged, true);
            app.SLPEditField.Value = 0.05;
            app.AMP_WKEditFieldLabel = uilabel(app.Param_Panel);
            app.AMP_WKEditFieldLabel.Text = 'AMP_WK';
            app.AMP_WKEditField = uieditfield(app.Param_Panel, 'numeric');
            app.AMP_WKEditField.ValueChangedFcn = createCallbackFcn(app, @AMP_WKEditFieldValueChanged, true);
            app.AMP_WKEditField.Value = 1;
            app.TPERIODEditFieldLabel = uilabel(app.Param_Panel);
            app.TPERIODEditFieldLabel.Text = 'TPERIOD';
            app.TPERIODEditField = uieditfield(app.Param_Panel, 'numeric');
            app.TPERIODEditField.ValueChangedFcn = createCallbackFcn(app, @TPERIODEditFieldValueChanged, true);
            app.TPERIODEditField.Value = 9;
            app.FunwaveVariableParametersLabel = uilabel(app.Param_Panel);
            app.FunwaveVariableParametersLabel.Text = 'Funwave Variable Parameters';
            app.DataFolderButton = uibutton(app.Param_Panel, 'push');
            app.DataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @DataFolderButtonPushed, true);
            app.DataFolderButton.Text = 'Data Folder';
            app.PointtoPlotEditFieldLabel = uilabel(app.Param_Panel);
            app.PointtoPlotEditFieldLabel.Text = 'Point to Plot';
            app.PointtoPlotEditField = uieditfield(app.Param_Panel, 'numeric');
            app.PointtoPlotEditField.ValueChangedFcn = createCallbackFcn(app, @PointtoPlotEditFieldValueChanged, true);
            app.PointtoPlotEditField.Value = 900;
            app.LoadinDataLabel = uilabel(app.Param_Panel);
            app.LoadinDataLabel.Text = 'Load in Data';
            app.DataFolder = uieditfield(app.Param_Panel, 'text');
            app.FunwaveConstantParametersLabel = uilabel(app.Param_Panel);
            app.FunwaveConstantParametersLabel.Text = 'Funwave Constant Parameters';
            app.MglobEditFieldLabel = uilabel(app.Param_Panel);
            app.MglobEditFieldLabel.Text = 'Mglob';
            app.Mglob = uieditfield(app.Param_Panel, 'numeric');
            app.NglobEditFieldLabel = uilabel(app.Param_Panel);
            app.NglobEditFieldLabel.Text = 'Nglob';
            app.Nglob = uieditfield(app.Param_Panel, 'numeric');
            app.DXEditFieldLabel = uilabel(app.Param_Panel);
            app.DXEditFieldLabel.Text = 'DX';
            app.DX = uieditfield(app.Param_Panel, 'numeric');
            app.DYEditFieldLabel = uilabel(app.Param_Panel);
            app.DYEditFieldLabel.Text = 'DY';
            app.DY = uieditfield(app.Param_Panel, 'numeric');
            app.PXEditFieldLabel = uilabel(app.Param_Panel);
            app.PXEditFieldLabel.Text = 'PX';
            app.PX = uieditfield(app.Param_Panel, 'numeric');
            app.PYEditFieldLabel = uilabel(app.Param_Panel);
            app.PYEditFieldLabel.Text = 'PY';
            app.PY = uieditfield(app.Param_Panel, 'numeric');
            app.TOTAL_TIMEEditFieldLabel = uilabel(app.Param_Panel);
            app.TOTAL_TIMEEditFieldLabel.Text = 'TOTAL_TIME';
            app.TOTAL_TIME = uieditfield(app.Param_Panel, 'numeric');
            app.CFLEditFieldLabel = uilabel(app.Param_Panel);
            app.CFLEditFieldLabel.Text = 'CFL';
            app.CFL = uieditfield(app.Param_Panel, 'numeric');
            app.PLOT_INTVEditFieldLabel = uilabel(app.Param_Panel);
            app.PLOT_INTVEditFieldLabel.Text = 'PLOT_INTV';
            app.PLOT_INTV = uieditfield(app.Param_Panel, 'numeric');
            app.SCREEN_INTVEditFieldLabel = uilabel(app.Param_Panel);
            app.SCREEN_INTVEditFieldLabel.Text = 'SCREEN_INTV';
            app.SCREEN_INTV = uieditfield(app.Param_Panel, 'numeric');
            app.FroudeCapEditFieldLabel = uilabel(app.Param_Panel);
            app.FroudeCapEditFieldLabel.Text = 'FroudeCap';
            app.FroudeCap = uieditfield(app.Param_Panel, 'numeric');
            app.MinDepthEditFieldLabel = uilabel(app.Param_Panel);
            app.MinDepthEditFieldLabel.Text = 'MinDepth';
            app.MinDepth = uieditfield(app.Param_Panel, 'numeric');
            app.Label = uilabel(app.Param_Panel);
            app.Label.Text = '______________________________';
            app.Label_3 = uilabel(app.Param_Panel);
            app.Label_3.Text = '______________________________';
            app.Xc_WKEditFieldLabel = uilabel(app.Param_Panel);
            app.Xc_WKEditFieldLabel.Text = 'Xc_WK';
            app.Xc_WK_Box = uieditfield(app.Param_Panel, 'numeric');
            app.Theta_WKEditFieldLabel = uilabel(app.Param_Panel);
            app.Theta_WKEditFieldLabel.Text = 'Theta_WK';
            app.Theta_WK = uieditfield(app.Param_Panel, 'numeric');
            app.DEP_WKEditFieldLabel = uilabel(app.Param_Panel);
            app.DEP_WKEditFieldLabel.Text = 'DEP_WK';
            app.DEP_WK = uieditfield(app.Param_Panel, 'numeric');
            app.Label_4 = uilabel(app.Param_Panel);
            app.Label_4.Text = '______________________________';
            app.VISCOSITYBREAKINGEditFieldLabel = uilabel(app.Param_Panel);
            app.VISCOSITYBREAKINGEditFieldLabel.Text = {'VISCOSITY'; 'BREAKING'};
            app.VISCOSITYBREAKING = uieditfield(app.Param_Panel, 'text');
            app.FRICTIONSPONGEEditFieldLabel = uilabel(app.Param_Panel);
            app.FRICTIONSPONGEEditFieldLabel.Text = {'FRICTION '; 'SPONGE'};
            app.FRICTIONSPONGE = uieditfield(app.Param_Panel, 'text');
            app.DIRECTSPONGEEditFieldLabel = uilabel(app.Param_Panel);
            app.DIRECTSPONGEEditFieldLabel.Text = {'DIRECT'; 'SPONGE'};
            app.DIRECTSPONGE = uieditfield(app.Param_Panel, 'text');
            app.CDSpongeEditFieldLabel = uilabel(app.Param_Panel);
            app.CDSpongeEditFieldLabel.Text = 'CDSponge';
            app.CDSponge = uieditfield(app.Param_Panel, 'numeric');
            app.CspEditFieldLabel = uilabel(app.Param_Panel);
            app.CspEditFieldLabel.Text = 'Csp';
            app.Sponge_Csp = uieditfield(app.Param_Panel, 'numeric');
            app.WEditFieldLabel = uilabel(app.Param_Panel);
            app.WEditFieldLabel.Text = 'W';
            app.Sponge_W = uieditfield(app.Param_Panel, 'numeric');
            app.EEditFieldLabel = uilabel(app.Param_Panel);
            app.EEditFieldLabel.Text = 'E';
            app.Sponge_E = uieditfield(app.Param_Panel, 'numeric');
            app.SEditFieldLabel = uilabel(app.Param_Panel);
            app.SEditFieldLabel.Text = 'S';
            app.Sponge_S = uieditfield(app.Param_Panel, 'numeric');
            app.NEditFieldLabel = uilabel(app.Param_Panel);
            app.NEditFieldLabel.Text = 'N';
            app.Sponge_N = uieditfield(app.Param_Panel, 'numeric');
            app.SpongeDimensionsLabel = uilabel(app.Param_Panel);
            app.SpongeDimensionsLabel.Text = 'Sponge Dimensions';
            app.LineLabel = uilabel(app.Param_Panel);
            app.LineLabel.Text = '______________________________';
            app.CdEditFieldLabel = uilabel(app.Param_Panel);
            app.CdEditFieldLabel.Text = 'Cd';
            app.Cd = uieditfield(app.Param_Panel, 'numeric');
            app.Cbrk1EditFieldLabel = uilabel(app.Param_Panel);
            app.Cbrk1EditFieldLabel.Text = 'Cbrk1';
            app.Cbrk1 = uieditfield(app.Param_Panel, 'numeric');
            app.Cbrk2EditFieldLabel = uilabel(app.Param_Panel);
            app.Cbrk2EditFieldLabel.Text = 'Cbrk2';
            app.Cbrk2 = uieditfield(app.Param_Panel, 'numeric');
            app.DIFFUSIONSPONGEEditField_2Label = uilabel(app.Param_Panel);
            app.DIFFUSIONSPONGEEditField_2Label.Text = {'DIFFUSION'; 'SPONGE'};
            app.DIFFUSIONSPONGE = uieditfield(app.Param_Panel, 'text');
            app.WAVEMAKEREditFieldLabel = uilabel(app.Param_Panel);
            app.WAVEMAKEREditFieldLabel.Text = {'WAVE'; 'MAKER'};
            app.WAVEMAKER = uieditfield(app.Param_Panel, 'text');
            app.T_INTVMEANEditFieldLabel = uilabel(app.Param_Panel);
            app.T_INTVMEANEditFieldLabel.Text = {'T_INTV'; 'MEAN'};
            app.T_INTVMEAN = uieditfield(app.Param_Panel, 'numeric');
            app.Plot_Panel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Plot_Panel);
            title(app.UIAxes, 'Time Series at Point')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, '\eta')
            zlabel(app.UIAxes, 'Z')
            app.BathyGraph = uiaxes(app.Plot_Panel);
            title(app.BathyGraph, 'Associated Bathymetry')
            zlabel(app.BathyGraph, 'Z')
            app.SkasyGraph = uiaxes(app.Plot_Panel);
            title(app.SkasyGraph, 'Skew and Asymmetry at Each Point in the Cross-Shore')
            zlabel(app.SkasyGraph, 'Z')
            app.SkewandAsymmetryLabel = uilabel(app.Plot_Panel);
            app.SkewandAsymmetryLabel.Text = 'Skew and Asymmetry';
            app.SkewEditFieldLabel = uilabel(app.Plot_Panel);
            app.SkewEditFieldLabel.Text = 'Skew';
            app.Skew = uieditfield(app.Plot_Panel, 'numeric');
            app.Skew.Value = 0.05;
            app.AsymmetryEditFieldLabel = uilabel(app.Plot_Panel);
            app.AsymmetryEditFieldLabel.Text = 'Asymmetry';
            app.Asy = uieditfield(app.Plot_Panel, 'numeric');
            app.Asy.Value = 0.05;
            app.TrialBeingPlottedLabel = uilabel(app.Plot_Panel);
            app.TrialBeingPlottedLabel.Text = 'Trial Being Plotted';
            app.IterationLabel = uilabel(app.Plot_Panel);
            app.IterationLabel.Text = 'Iteration #';
            app.Iteration_No = uieditfield(app.Plot_Panel, 'numeric');
            app.IterationLabel_2 = uilabel(app.Plot_Panel);
            app.IterationLabel_2.Text = 'SLP';
            app.Input_SLP = uieditfield(app.Plot_Panel, 'numeric');
            app.Input_SLP.Value = 0.05;
            app.IterationLabel_3 = uilabel(app.Plot_Panel);
            app.IterationLabel_3.Text = 'AMP_WK';
            app.Input_AMP_WK = uieditfield(app.Plot_Panel, 'numeric');
            app.Input_AMP_WK.Value = 0.05;
            app.IterationLabel_4 = uilabel(app.Plot_Panel);
            app.IterationLabel_4.Text = 'TPERIOD';
            app.Input_TPERIOD = uieditfield(app.Plot_Panel, 'numeric');
            app.Input_TPERIOD.Value = 0.05;
            app.INPUTSLabel = uilabel(app.Plot_Panel);
            app.INPUTSLabel.Text = {'I'; 'N'; 'P'; 'U'; 'T'; 'S'};
            app.Label_5 = uilabel(app.Plot_Panel);
            app.Label_5.Text = '_______________________';
            app.IterationLabel_5 = uilabel(app.Plot_Panel);
            app.IterationLabel_5.Text = 'Xslp';
            app.Xslp_Box = uieditfield(app.Plot_Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FW_1D_Time_Series_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end