classdef Visualize_Field_Data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        h_taken                   matlab.ui.control.NumericEditField
        htakenLabel               matlab.ui.control.Label
        OffshoreConditionsforTrialLabel  matlab.ui.control.Label
        WavePeriod                matlab.ui.control.NumericEditField
        WavePeriodEditFieldLabel  matlab.ui.control.Label
        WaveHeight                matlab.ui.control.NumericEditField
        WaveHeightEditFieldLabel  matlab.ui.control.Label
        ErrorBox                  matlab.ui.control.EditField
        ErrorEditFieldLabel       matlab.ui.control.Label
        PlotBathymetryButton      matlab.ui.control.Button
        Trial_No                  matlab.ui.control.NumericEditField
        TrialEditFieldLabel       matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        DataLoading               matlab.ui.control.EditField
        RightPanel                matlab.ui.container.Panel
        sflip                     matlab.ui.control.UIAxes
        AsGiven                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        work_dir = 'C:/Users/rschanta/ML-Funwave-Work/'
        D3_dir = 'Validation-Data/DUNE3_data/' 
        data % The Dune3 Data Loaded in
        trial_no = 5% Trial Number to Plot
        Tp
        Hs
        h
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: AsGiven
        function AsGivenButtonDown(app, event)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.DataLoading.Value = 'Loading Finished4';
            app.data = load(fullfile(app.work_dir,app.D3_dir,'D3_struct.mat'));
            app.DataLoading.Value = 'Data Loaded';
        end

        % Button pushed function: PlotBathymetryButton
        function PlotBathymetryButtonPushed(app, event)
            try
                rd = app.data.(['Trial',sprintf('%02d', app.trial_no)]).raw_data;

                % Plot bathymetry as given
                    cla(app.AsGiven)
                    hold(app.AsGiven,"on");
                    
                    % Plot Before and After Profiles
                    plot(app.AsGiven,rd.bed_before(:,1),-rd.bed_before(:,2),'LineWidth',1.5,'Color','b','LineStyle','-');
                    plot(app.AsGiven,rd.bed_after(:,1),-rd.bed_after(:,2),'LineWidth',1.5,'Color','r','LineStyle','-');
                    
                    % Plot Position of Wave Gauges
                    gauges = rd.WG_loc_x;
                    plot(app.AsGiven,[gauges; gauges], repmat(ylim(app.AsGiven)',1,size(gauges,2)), 'Color',[0 0 0,0.5],'LineStyle','--','LineWidth',0.75);

                    % Plot Position of Offshore Conditions
                    [~, index] = min(abs(rd.bed_before(:,2) - app.h));
                    xline(app.AsGiven,rd.bed_before(index,1), 'LineStyle','-','Color','g','LineWidth',2)
                    
                    % Plot Propreties
                    grid(app.AsGiven,'on');
                    legend(app.AsGiven,'Bed Before','Bed After','Offshore', 'Wave Gauges', 'Location','southwest');

                % 
                % Plot bathymetry shifted and flipped
                    % Initial Work
                        % Shift to the left
                            Xshift_bef = rd.bed_before(:,1)' - min(rd.bed_before(:,1));
                            Xshift_aft = rd.bed_after(:,1)' - min(rd.bed_after(:,1));
                            WG_shift = gauges - min(rd.bed_before(:,1));
                            
                            % Flip about 
                            Xsflip_bef = fliplr(Xshift_bef);
                            Xsflip_aft = fliplr(Xshift_aft);
                            WG_sflip = max(Xshift_bef) - WG_shift;
                    
                    % Plot
                    cla(app.sflip)
                    hold(app.sflip,"on")
                    
                    % Plot Before and After Profiles
                    plot(app.sflip,Xsflip_bef,-rd.bed_before(:,2),'LineWidth',1.5,'Color','b','LineStyle','-')
                    plot(app.sflip,Xsflip_aft,-rd.bed_after(:,2),'LineWidth',1.5,'Color','r','LineStyle','-')
                    
                    % Plot Position of Offshore Conditions
                    [~, index] = min(abs(rd.bed_before(:,2) - app.h));
                    xline(app.sflip,Xsflip_bef(index), 'LineStyle','-','Color','g','LineWidth',2)
                    
                    % Plot Wave Gauges
                    plot(app.sflip,[WG_sflip; WG_sflip], repmat(ylim(app.sflip)',1,size(WG_sflip,2)), 'Color',[0 0 0,0.5],'LineStyle','--','LineWidth',0.75);
                    
                    % Plot Propreties
                    grid(app.sflip,'on');
                    legend(app.sflip,'Bed Before','Bed After','Offshore', 'Wave Gauges', 'Location','northwest');


            catch
                app.ErrorBox.Value = 'Trial Not Found';
            end

        end

        % Value changed function: Trial_No
        function Trial_NoValueChanged(app, event)
            value = app.Trial_No.Value;
            app.trial_no = value;

            % Set offshore conditions
            wc = app.data.(['Trial',sprintf('%02d', app.trial_no)]).wave_condition;
            app.Tp = wc.Tp;
            app.WavePeriod.Value = wc.Tp;
            app.Hs = wc.Hs;
            app.WaveHeight.Value = wc.Hs;
            app.h = wc.h;
            app.h_taken.Value = wc.h;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {548, 548};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataLoading = uieditfield(app.LeftPanel, 'text');
            app.DataLoading.Value = 'Data Not Loaded';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TrialEditFieldLabel = uilabel(app.LeftPanel);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.Trial_No = uieditfield(app.LeftPanel, 'numeric');
            app.Trial_No.ValueChangedFcn = createCallbackFcn(app, @Trial_NoValueChanged, true);
            app.Trial_No.Value = 5;
            app.PlotBathymetryButton = uibutton(app.LeftPanel, 'push');
            app.PlotBathymetryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBathymetryButtonPushed, true);
            app.PlotBathymetryButton.Text = 'Plot Bathymetry';
            app.ErrorEditFieldLabel = uilabel(app.LeftPanel);
            app.ErrorEditFieldLabel.Text = 'Error ';
            app.ErrorBox = uieditfield(app.LeftPanel, 'text');
            app.WaveHeightEditFieldLabel = uilabel(app.LeftPanel);
            app.WaveHeightEditFieldLabel.Text = 'Wave Height';
            app.WaveHeight = uieditfield(app.LeftPanel, 'numeric');
            app.WavePeriodEditFieldLabel = uilabel(app.LeftPanel);
            app.WavePeriodEditFieldLabel.Text = 'Wave Period';
            app.WavePeriod = uieditfield(app.LeftPanel, 'numeric');
            app.OffshoreConditionsforTrialLabel = uilabel(app.LeftPanel);
            app.OffshoreConditionsforTrialLabel.Text = 'Offshore Conditions for Trial';
            app.htakenLabel = uilabel(app.LeftPanel);
            app.htakenLabel.Text = 'h taken';
            app.h_taken = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.AsGiven = uiaxes(app.RightPanel);
            title(app.AsGiven, 'Bathymetry as Given for Trial')
            xlabel(app.AsGiven, 'X')
            ylabel(app.AsGiven, 'Y')
            zlabel(app.AsGiven, 'Z')
            app.AsGiven.ButtonDownFcn = createCallbackFcn(app, @AsGivenButtonDown, true);
            app.sflip = uiaxes(app.RightPanel);
            title(app.sflip, 'Bathymetry Shifted and Flipped Left')
            xlabel(app.sflip, 'X')
            ylabel(app.sflip, 'Y')
            zlabel(app.sflip, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_Field_Data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end